classdef CurrentWeatherAppDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CityLabel               matlab.ui.control.Label
        ThecurrentcityisLabel   matlab.ui.control.Label
        CityListBox             matlab.ui.control.ListBox
        CityListBoxLabel        matlab.ui.control.Label
        UpdateButton            matlab.ui.control.Button
        Switch                  matlab.ui.control.Switch
        HumidityGauge           matlab.ui.control.LinearGauge
        HumidityGaugeLabel      matlab.ui.control.Label
        PressuremmHgGauge       matlab.ui.control.Gauge
        PressuremmHgGaugeLabel  matlab.ui.control.Label
        TemperatureGauge        matlab.ui.control.Gauge
        TemperatureGaugeLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        unitChoice = "metric"  % This will be either "imperial" (Fahrenheit) or "metric" (Celsius)
        apiKey                 % apiKey value for the API call
    end
    
    
    methods (Access = private)

        function [lat,lon] = LookupCoordinates(app)
            idx = app.CityListBox.ValueIndex;
            coords = [42.2834 -71.3495; ... % Natick
                4.6097 -74.0817; ...        % Bogota
                12.9762 77.6033; ...        % Bengaluru
                48.1391 11.5802; ...        % Munich
                -26.2023 28.0436; ...       % Johannesburg
                35.6895 139.6917;...        % Tokyo
                -33.8688 151.2093];         % Sydney
            lat = coords(idx,1);
            lon = coords(idx,2); 
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if pyenv().Status ~= "Loaded"
                addpath(pyenv().Home)
            end
            try
                app.apiKey = readlines("myAPIkey.txt",EmptyLineRule="skip");
            catch ME
                app.CityLabel.Text = "Need API key";
                return
            end

            % Initialize all gauge values to the minimum, as expected for a
            % non-functional gauge
            app.HumidityGauge.Value = app.HumidityGauge.Limits(1);
            app.PressuremmHgGauge.Value = app.PressuremmHgGauge.Limits(1);
            app.TemperatureGauge.Value = app.TemperatureGauge.Limits(1);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            % Use the unit chosen by the switch to update the
            % temperature gauge units, as well as the unitChoice to be
            % requested by the API calls
            switch string(app.Switch.Value)
                case "C"
                    app.unitChoice = "metric";
                    app.TemperatureGauge.Limits = [-20 50];
                    app.TemperatureGauge.Value = (app.TemperatureGauge.Value-32)*5/9;
                case "F"
                    app.unitChoice = "imperial";
                    app.TemperatureGauge.Limits = [-4 122];
                    app.TemperatureGauge.Value = (app.TemperatureGauge.Value)*9/5+32;
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Identify the current city the user has selected
            app.CityLabel.Text = string(app.CityListBox.Value);
            [lat,lon] = LookupCoordinates(app);

            % Move to the folder where the Python script lives
            currentDirectory = pwd;
            neededDirectory = extractBefore(which("checkcurrentweather.py"),"checkcurrentweather.py");
            cd(neededDirectory)

            % % Running Python statements generated from the Run Python Live Task
            pythonCode = [
                "import checkcurrentweather",...
                "json_data = checkcurrentweather.get_weather(lat,lon,apikey,units = unitChoice)", ...
                "currentWeather = checkcurrentweather.parse_current_json(json_data)"
                ];
            [currentWeather] = pyrun(pythonCode, ["currentWeather"], "lat", lat, "lon", lon, "apikey",...
                app.apiKey, "unitChoice", app.unitChoice);

            % Return to the original directory
            cd(currentDirectory)

            % Reformat the results for ease of extracting the values of
            % temperature, humidity, and pressure
            curWeather = struct(currentWeather);
            T = struct2table(curWeather,"RowNames",string(curWeather.city_name));

            % The API returns pressure in hPa
            u = symunit;
            [pressuremmHg,~] = separateUnits(rewrite(T.pressure*u.hPa,u.mmHg));

            % Set appropriate values on the display
            app.HumidityGauge.Value = T.humidity;
            app.TemperatureGauge.Value = T.temp;
            app.PressuremmHgGauge.Value = double(pressuremmHg);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TemperatureGaugeLabel = uilabel(app.UIFigure);
            app.TemperatureGaugeLabel.Text = 'Temperature';
            app.TemperatureGauge = uigauge(app.UIFigure, 'circular');
            app.TemperatureGauge.Limits = [-20 50];
            app.PressuremmHgGaugeLabel = uilabel(app.UIFigure);
            app.PressuremmHgGaugeLabel.Text = 'Pressure (mmHg)';
            app.PressuremmHgGauge = uigauge(app.UIFigure, 'circular');
            app.PressuremmHgGauge.Limits = [740 780];
            app.HumidityGaugeLabel = uilabel(app.UIFigure);
            app.HumidityGaugeLabel.Text = 'Humidity (%)';
            app.HumidityGauge = uigauge(app.UIFigure, 'linear');
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'C', 'F'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'C';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.CityListBoxLabel = uilabel(app.UIFigure);
            app.CityListBoxLabel.Text = 'City';
            app.CityListBox = uilistbox(app.UIFigure);
            app.CityListBox.Items = {'Natick, US', 'Bogota, Colombia', 'Bengaluru, India', 'Munich, Germany', 'Johannesburg, ZA', 'Tokyo, Japan', 'Sydney, Australia'};
            app.CityListBox.Value = 'Natick, US';
            app.ThecurrentcityisLabel = uilabel(app.UIFigure);
            app.ThecurrentcityisLabel.Text = 'The current city is: ';
            app.CityLabel = uilabel(app.UIFigure);
            app.CityLabel.Text = 'none';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurrentWeatherAppDemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end