classdef CurrentWeatherAppStart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UpdateButton        matlab.ui.control.Button
        CityListBox         matlab.ui.control.ListBox
        CityListBoxLabel    matlab.ui.control.Label
        HumidityGauge       matlab.ui.control.LinearGauge
        HumidityGaugeLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Identify the current city the user has selected
idx = app.CityListBox.ValueIndex;
coords = [42.2834 -71.3495; ... % Natick
    4.6097 -74.0817; ...        % Bogota
    12.9762 77.6033; ...        % Bengaluru
    48.1391 11.5802; ...        % Munich
    -26.2023 28.0436; ...       % Johannesburg
    35.6895 139.6917;...        % Tokyo
    -33.8688 151.2093];         % Sydney
lat = coords(idx,1);
lon = coords(idx,2);

% Read the API key
apiKey = readlines("myAPIkey.txt",EmptyLineRule="skip");

% Move to the folder where the Python script lives
currentDirectory = pwd;
neededDirectory = extractBefore(which("checkcurrentweather.py"),"checkcurrentweather.py");
cd(neededDirectory)

% % Running Python statements generated from the Run Python Live Task
pythonCode = [
    "import checkcurrentweather",...
    "json_data = checkcurrentweather.get_weather(lat,lon,apikey,units = unitChoice)", ...
    "currentWeather = checkcurrentweather.parse_current_json(json_data)"
    ];
[currentWeather] = pyrun(pythonCode, "currentWeather", "lat", lat, "lon", lon, ...
    "apikey", apiKey, "unitChoice", "standard");

% Return to the original directory
cd(currentDirectory)

% Reformat the results for ease of extracting the values
curWeather = struct(currentWeather);
T = struct2table(curWeather,"RowNames",string(curWeather.city_name));

% Set appropriate values on the display
app.HumidityGauge.Value = T.humidity;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HumidityGaugeLabel = uilabel(app.UIFigure);
            app.HumidityGaugeLabel.Text = 'Humidity (%)';
            app.HumidityGauge = uigauge(app.UIFigure, 'linear');
            app.CityListBoxLabel = uilabel(app.UIFigure);
            app.CityListBoxLabel.Text = 'City';
            app.CityListBox = uilistbox(app.UIFigure);
            app.CityListBox.Items = {'Natick, US', 'Bogota, Colombia', 'Bengaluru, India', 'Munich, Germany', 'Johannesburg, ZA', 'Tokyo, Japan', 'Sydney, Australia'};
            app.CityListBox.Value = 'Natick, US';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurrentWeatherAppStart

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end