classdef WeatherDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        WarningArea                matlab.ui.control.TextArea
        ChooseThemeDropDown        matlab.ui.control.DropDown
        ChooseThemeDropDownLabel   matlab.ui.control.Label
        UnitsButtonGroup           matlab.ui.container.ButtonGroup
        FButton                    matlab.ui.control.RadioButton
        CButton                    matlab.ui.control.RadioButton
        UITable                    matlab.ui.control.Table
        UpdateButton               matlab.ui.control.Button
        SelectedcityDropDown       matlab.ui.control.DropDown
        SelectedcityDropDownLabel  matlab.ui.control.Label
        CityLabel                  matlab.ui.control.Label
        HumidityGauge              matlab.ui.control.LinearGauge
        HumidityLabel              matlab.ui.control.Label
        PressuremmHgGauge          matlab.ui.control.Gauge
        PressuremmHgGaugeLabel     matlab.ui.control.Label
        TemperatureGauge           matlab.ui.control.Gauge
        TemperatureGaugeLabel      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        apiKey % Description
        % Pax
        lat = nan;
        lon = nan;
        unitChoice = "imperial";
        curCity = "";
        curHour = 12;
        curMin = 0;
        MinHand
        HrHand
        localForegroundColor
        localBackgroundColor
    end

    methods (Access = private)


        function CreateClock(app)
            axis(app.UIAxes,"off","square")

            plot(app.UIAxes,[0 0],[0.8 1],LineWidth=1.5)
            hold(app.UIAxes,"on")
            rectangle(app.UIAxes,Position=[-1 -1 2 2],Curvature=1,LineWidth=3,FaceColor=app.localBackgroundColor,EdgeColor=app.localForegroundColor)
            rectangle(app.UIAxes,Position=[-0.05 -0.05 0.1 0.1],Curvature=1,FaceColor=app.localForegroundColor)

            for mins = 1:59
                plot(app.UIAxes,[.9*sin(mins*pi/30) sin(mins*pi/30)],[.9*cos(mins*pi/30) cos(mins*pi/30)],Color=app.localForegroundColor)
            end

            for hr = 1:12
                plot(app.UIAxes,[.8*sin(hr*pi/6) sin(hr*pi/6)],[.8*cos(hr*pi/6) cos(hr*pi/6)],Color=app.localForegroundColor,LineWidth=1.5)
                if hr<6
                    text(app.UIAxes,1.1*sin((hr)*pi/6),1.1*cos((hr)*pi/6),string(hr),Color=app.localForegroundColor)
                elseif hr<10
                    text(app.UIAxes,1.1*sin((hr)*pi/6)-.03,1.1*cos((hr)*pi/6),string(hr),Color=app.localForegroundColor)
                elseif hr<12
                    text(app.UIAxes,1.1*sin((hr)*pi/6)-.08,1.1*cos((hr)*pi/6),string(hr),Color=app.localForegroundColor)
                end
            end


            app.MinHand =  plot(app.UIAxes,[0 0.75*sin(app.curMin*pi/30)],[0 0.75*cos(app.curMin*pi/30)],SeriesIndex=2);
            app.HrHand = plot(app.UIAxes,[0 0.5*sin(app.curHour*pi/6)],[0 0.5*cos(app.curHour*pi/6)],SeriesIndex=1,LineWidth=3);
            hold(app.UIAxes,"off")
        end

        function SetCurrentTime(app)
            t = datetime("now");
            curSeconds = second(t);
            app.curMin = minute(t)+curSeconds/60;
            app.curHour = hour(t)+app.curMin/60;
            UpdateDisplayedTime(app)
        end

        function UpdateDisplayedTime(app)
            app.MinHand.XData = [0 0.75*sin(app.curMin*pi/30)];
            app.MinHand.YData = [0 0.75*cos(app.curMin*pi/30)];
            app.HrHand.XData = [0 0.5*sin(app.curHour*pi/6)];
            app.HrHand.YData = [0 0.5*cos(app.curHour*pi/6)];
        end

        function ResetValues(app)
            app.HumidityGauge.Value = app.HumidityGauge.Limits(1);
            app.TemperatureGauge.Value = app.TemperatureGauge.Limits(1);
            app.PressuremmHgGauge.Value = app.PressuremmHgGauge.Limits(1);
            app.UITable.Data = string([datetime("now","Format","dd-MMM-uuuu") NaT NaT]);
            app.CityLabel.Text = "";
            SetCurrentTime(app)
        end

        function SetColors(app)
            switch app.ChooseThemeDropDown.Value
                case "Dark"
                    app.localForegroundColor = [1 1 1];
                    app.localBackgroundColor = [0.12 0.12 0.12];
                case "Light"
                    app.localForegroundColor = [0 0 0];
                    app.localBackgroundColor = [0.94 0.94 0.94];
            end
            app.UIAxes.Color = app.localBackgroundColor;
            app.UIAxes.Title.Color = app.localForegroundColor;
            app.UIFigure.Color = app.localBackgroundColor;
            app.WarningArea.BackgroundColor = app.localBackgroundColor;
            app.WarningArea.FontColor = app.localForegroundColor;
            % app.UITable.BackgroundColor = [round(app.localBackgroundColor); app.localBackgroundColor];
            % app.UITable.ForegroundColor = app.localForegroundColor;
            app.CityLabel.FontColor = app.localForegroundColor;
            app.HumidityLabel.FontColor = app.localForegroundColor;
            app.TemperatureGaugeLabel.FontColor = app.localForegroundColor;
            app.SelectedcityDropDownLabel.FontColor = app.localForegroundColor;
            app.PressuremmHgGaugeLabel.FontColor = app.localForegroundColor;
            app.UnitsButtonGroup.ForegroundColor = app.localForegroundColor;
            app.UnitsButtonGroup.BackgroundColor = app.UIFigure.Color;
            app.FButton.FontColor = app.localForegroundColor;
            app.CButton.FontColor = app.localForegroundColor;
            % app.TemperatureGauge.FontColor = app.localForegroundColor;
            % app.TemperatureGauge.BackgroundColor = app.localBackgroundColor;
            % app.PressuremmHgGauge.BackgroundColor = app.localBackgroundColor;
            % app.PressuremmHgGauge.FontColor = app.localForegroundColor;
            % app.HumidityGauge.FontColor = app.localForegroundColor;
            % app.HumidityGauge.BackgroundColor = app.localBackgroundColor;
            app.ChooseThemeDropDownLabel.FontColor = app.localForegroundColor;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if pyenv().Status ~= "Loaded"
                addpath(pyenv().Home)
            end
            app.ChooseThemeDropDown.Value = "Dark";
            SetColors(app)
            CreateClock(app)
            try
                app.apiKey = readlines("myAPIkey.txt");
            catch ME
                app.CityLabel.Text = "Need API key";
                return
            end
            % app.UITable.Data = string([datetime("now","Format","dd-MMM-uuuu") NaT NaT]);

            app.PressuremmHgGauge.Tooltip = "mmHg";
            app.PressuremmHgGaugeLabel.Tooltip = "mmHg";


            % SetTime(app)
            UnitsButtonGroupSelectionChanged(app)
            ResetValues(app)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            if app.CityLabel.Text == "Need API key"
                app.UITable.Visible = "off";
                app.WarningArea.Visible = "on";
                app.WarningArea.Value = "Please set up your OpenWeather API key as described in CheckingTheWeather.mlx. Then restart this app.";
            else
                SelectedcityDropDownValueChanged(app, event)
                app.CityLabel.Text = app.curCity;

                % The Python library must be in the current directory
                currentDirectory = pwd;
                neededDirectory = extractBefore(which("checkcurrentweather.py"),"checkcurrentweather.py");
                cd(neededDirectory)

                % % Replace with actual python call
                % % Running Python statements generated from the Run Python Live Task
                pythonCode = [
                    "import checkcurrentweather",...
                    "json_data = checkcurrentweather.get_weather(lat,lon,apikey,units = unitChoice)", ...
                    "currentWeather = checkcurrentweather.parse_current_json(json_data)"
                    ];
                [currentWeather] = pyrun(pythonCode, "currentWeather", "lat", app.lat, "lon", app.lon, "apikey",...
                    app.apiKey, "unitChoice", app.unitChoice);
                cd(currentDirectory)
                curWeather = struct(currentWeather);

                T = struct2table(curWeather,"RowNames",string(curWeather.city_name));

                T.current_time = datetime(double(T.current_time+T.timezone),"ConvertFrom","posixtime");
                T.sunrise = datetime(double(T.sunrise+T.timezone),"ConvertFrom","posixtime");
                T.sunset = datetime(double(T.sunset+T.timezone),"ConvertFrom","posixtime");
                T = removevars(T,{'timezone','city_name'});

                % load("output.mat","T")
                % %

                app.curMin = minute(T.current_time)+second(T.current_time)/60;
                app.curHour = mod(hour(T.current_time),12)+app.curMin/60;

                UpdateDisplayedTime(app)

                app.UITable.Data = [string(datetime(T.current_time,"Format","MMM d, uuuu")) string(timeofday(T.sunrise)) string(timeofday(T.sunset))];

                app.HumidityGauge.Value = T.humidity;
                app.TemperatureGauge.Value = T.temp;
                u = symunit;
                [pressuremmHg,~] = separateUnits(rewrite(T.pressure*u.hPa,u.mmHg));
                app.PressuremmHgGauge.Value = double(pressuremmHg);
            end
        end

        % Selection changed function: UnitsButtonGroup
        function UnitsButtonGroupSelectionChanged(app, event)
            selectedButton = app.UnitsButtonGroup.SelectedObject;

            % tMin = -20*u.Celsius
            % unitConvert(tMin,u.Fahrenheit)

            switch selectedButton.Text
                case "C"
                    app.unitChoice = "metric";
                    app.TemperatureGaugeLabel.Tooltip = "C";
                    app.TemperatureGauge.Tooltip = "C";
                    app.TemperatureGauge.Limits = [-20 50];
                    app.TemperatureGauge.Value = (app.TemperatureGauge.Value-32)*5/9;
                case "F"
                    app.unitChoice = "imperial";
                    app.TemperatureGaugeLabel.Tooltip = "F";
                    app.TemperatureGauge.Tooltip = "F";
                    app.TemperatureGauge.Limits = [-4 122];
                    app.TemperatureGauge.Value = (app.TemperatureGauge.Value)*9/5+32;
            end
        end

        % Value changed function: SelectedcityDropDown
        function SelectedcityDropDownValueChanged(app, event)
            location = string(app.SelectedcityDropDown.Value);
            if location ~= app.curCity
                app.curCity = location;
                ResetValues(app)
            end
            cities = ["Natick, US"; "Bogota, Columbia"; "Bengaluru, India"; "Johannesburg, ZA"; "Tokyo, Japan"];
            idx = find(cities==location);
            coords = [42.2834 -71.3495; ... % Natick
                4.6097 -74.0817; ...        % Bogota
                12.9762 77.6033; ...        % Bengaluru
                -26.2023 28.0436; ...       % Johannesburg
                35.6895 139.6917];          % Tokyo
            app.lat = coords(idx,1);
            app.lon = coords(idx,2);
        end

        % Value changed function: ChooseThemeDropDown
        function ChooseThemeDropDownValueChanged(app, event)
            SetColors(app)
            CreateClock(app)
            UpdateDisplayedTime(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TemperatureGaugeLabel = uilabel(app.UIFigure);
            app.TemperatureGaugeLabel.Text = 'Temperature';
            app.TemperatureGauge = uigauge(app.UIFigure, 'circular');
            app.TemperatureGauge.Limits = [-20 50];
            app.TemperatureGauge.Value = -20;
            app.PressuremmHgGaugeLabel = uilabel(app.UIFigure);
            app.PressuremmHgGaugeLabel.Text = 'Pressure (mmHg)';
            app.PressuremmHgGauge = uigauge(app.UIFigure, 'circular');
            app.PressuremmHgGauge.Limits = [740 780];
            app.HumidityLabel = uilabel(app.UIFigure);
            app.HumidityLabel.Text = 'Humidity (%)';
            app.HumidityGauge = uigauge(app.UIFigure, 'linear');
            app.HumidityGauge.MajorTicks = [0 20 40 60 80 100];
            app.HumidityGauge.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.HumidityGauge.MinorTicks = [10 30 50 70 90];
            app.CityLabel = uilabel(app.UIFigure);
            app.CityLabel.Text = 'Natick, US';
            app.SelectedcityDropDownLabel = uilabel(app.UIFigure);
            app.SelectedcityDropDownLabel.Text = 'Selected city: ';
            app.SelectedcityDropDown = uidropdown(app.UIFigure);
            app.SelectedcityDropDown.Items = {'Natick, US ', 'Bogota, Columbia ', 'Bengaluru, India ', 'Johannesburg, ZA', 'Tokyo, Japan'};
            app.SelectedcityDropDown.ItemsData = {'Natick, US', 'Bogota, Columbia', 'Bengaluru, India', 'Johannesburg, ZA', 'Tokyo, Japan'};
            app.SelectedcityDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectedcityDropDownValueChanged, true);
            app.SelectedcityDropDown.Value = 'Natick, US';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Date'; 'Sunrise'; 'Sunset'};
            app.UITable.RowName = {};
            app.UnitsButtonGroup = uibuttongroup(app.UIFigure);
            app.UnitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UnitsButtonGroupSelectionChanged, true);
            app.UnitsButtonGroup.Title = 'Units';
            app.CButton = uiradiobutton(app.UnitsButtonGroup);
            app.CButton.Text = 'C';
            app.CButton.Value = true;
            app.FButton = uiradiobutton(app.UnitsButtonGroup);
            app.FButton.Text = 'F';
            app.ChooseThemeDropDownLabel = uilabel(app.UIFigure);
            app.ChooseThemeDropDownLabel.Text = 'Choose Theme';
            app.ChooseThemeDropDown = uidropdown(app.UIFigure);
            app.ChooseThemeDropDown.Items = {'Dark', 'Light'};
            app.ChooseThemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseThemeDropDownValueChanged, true);
            app.ChooseThemeDropDown.Value = 'Dark';
            app.WarningArea = uitextarea(app.UIFigure);
            app.WarningArea.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WeatherDisplay

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end